classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        firstcord               matlab.ui.control.Label
        secondcord              matlab.ui.control.Label
        PrecisionDropDownLabel  matlab.ui.control.Label
        PrecisionDropDown       matlab.ui.control.DropDown
        yEditFieldLabel         matlab.ui.control.Label
        y1cord                  matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        x1cord                  matlab.ui.control.NumericEditField
        yEditField_2Label       matlab.ui.control.Label
        y2cord                  matlab.ui.control.NumericEditField
        xEditField_2Label       matlab.ui.control.Label
        x2cord                  matlab.ui.control.NumericEditField
        heightlabel             matlab.ui.control.Label
        hLabel                  matlab.ui.control.Label
        height                  matlab.ui.control.NumericEditField
        GeneratematrixButton    matlab.ui.control.Button
        hLabel_2                matlab.ui.control.Label
        hsize                   matlab.ui.control.NumericEditField
        sizelabel               matlab.ui.control.Label
        yEditField_2Label_2     matlab.ui.control.Label
        ysize                   matlab.ui.control.NumericEditField
        xEditField_2Label_2     matlab.ui.control.Label
        xsize                   matlab.ui.control.NumericEditField
        InitprogramLabel        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: GeneratematrixButton
        function GeneratematrixButtonPushed(app, event)
            %% Load values
            start_x=50;
            start_y=50;
            end_x=100;
            end_y=110;
            precision=15;
            %probe_size in radius
            probe_size=15;
            size_x=200;
            size_y=200;
            height=50;
            %% Bottom part
            bot_nlodret=floor((start_y-probe_size)/precision);
            bot_nvandret=floor(size_x/precision);
            %x start out in
            x = 0:precision:size_x;
            y = repmat(start_y-probe_size-bot_nlodret*precision,1,length(0:precision:size_x));
            
            for i=1:1:bot_nlodret
                if mod(i,2) == 0
                    x=[x,0:precision:size_x];
                else
                    x=[x,fliplr(0:precision:size_x)];
                end
                y=[y,repmat(start_y-probe_size-(bot_nlodret-i)*precision,1,length(0:precision:size_x))];
            end
            
            %% Left or right part
            if mod(bot_nlodret,2)==1
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
            end
            
            %% Top part
            top_nlodret=floor((size_y-end_y+probe_size)/precision);
            top_nvandret=floor(size_x/precision);
            % Starting from left
            if mod(bot_nlodret,2)==1
                for i=0:1:top_nvandret
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            %Starting from right    
            else
                for i=top_nvandret:-1:0
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            end
            
            %% Remaining left or right part
            if mod(bot_nlodret,2)==0
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
                
            end
            n=length(x);
            x_temp=x;
            y_temp=y;
            z=zeros(1,n);
            %% Adding z layers
            for i=1:1:height/precision
                x=[x,x_temp];
                y=[y,y_temp];
                z=[z,repmat(i*precision,1,n)];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.firstcord = uilabel(app.UIFigure);
            app.firstcord.Text = 'First coordinate of object';
            app.secondcord = uilabel(app.UIFigure);
            app.secondcord.Text = 'Second coordinate of object';
            app.PrecisionDropDownLabel = uilabel(app.UIFigure);
            app.PrecisionDropDownLabel.Text = 'Precision';
            app.PrecisionDropDown = uidropdown(app.UIFigure);
            app.PrecisionDropDown.Items = {'1mm', '2mm', '5mm'};
            app.PrecisionDropDown.Value = '1mm';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.y1cord = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.x1cord = uieditfield(app.UIFigure, 'numeric');
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.y2cord = uieditfield(app.UIFigure, 'numeric');
            app.xEditField_2Label = uilabel(app.UIFigure);
            app.xEditField_2Label.Text = 'x';
            app.x2cord = uieditfield(app.UIFigure, 'numeric');
            app.heightlabel = uilabel(app.UIFigure);
            app.heightlabel.Text = 'Height of object';
            app.hLabel = uilabel(app.UIFigure);
            app.hLabel.Text = 'h';
            app.height = uieditfield(app.UIFigure, 'numeric');
            app.GeneratematrixButton = uibutton(app.UIFigure, 'push');
            app.GeneratematrixButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratematrixButtonPushed, true);
            app.GeneratematrixButton.Text = 'Generate matrix';
            app.hLabel_2 = uilabel(app.UIFigure);
            app.hLabel_2.Text = 'h';
            app.hsize = uieditfield(app.UIFigure, 'numeric');
            app.sizelabel = uilabel(app.UIFigure);
            app.sizelabel.Text = 'Size of measurement area';
            app.yEditField_2Label_2 = uilabel(app.UIFigure);
            app.yEditField_2Label_2.Text = 'y';
            app.ysize = uieditfield(app.UIFigure, 'numeric');
            app.xEditField_2Label_2 = uilabel(app.UIFigure);
            app.xEditField_2Label_2.Text = 'x';
            app.xsize = uieditfield(app.UIFigure, 'numeric');
            app.InitprogramLabel = uilabel(app.UIFigure);
            app.InitprogramLabel.Text = 'Init program';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end